classdef ECG_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SwavePeaksButton          matlab.ui.control.Button
        RwavePeaksButton          matlab.ui.control.Button
        TimeHigherEditField       matlab.ui.control.NumericEditField
        TimeHigherEditFieldLabel  matlab.ui.control.Label
        TimeLowerEditField        matlab.ui.control.NumericEditField
        TimeLowerEditFieldLabel   matlab.ui.control.Label
        ApplyFilterCheckBox       matlab.ui.control.CheckBox
        RefreshButton             matlab.ui.control.Button
        PatientDropDown           matlab.ui.control.DropDown
        PatientDropDownLabel      matlab.ui.control.Label
        ECGsinMatlabProjectLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

   % Jeremiah Gault
   % Code to run GUI that displays ECG signals
   % To run this code LoadMITecg and all database files are in the same
   % folder as the GUI file.
    properties (Access = private)
        data % Data
        x2plot % Data for x-axis
        y2plot % Data for y-axis
        time1 % Low bound
        time2 % high bound
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            patientfiles = dir('*.mat');

            for n = 1:length(patientfiles)
                Name{n} = patientfiles(n).name(1:4);
                my_items{n} = ['p_' Name{n}];
                [app.data.(my_items{n}).t, app.data.(my_items{n}).ecg, ...
                    app.data.(my_items{n}).Fs] = LoadMITecg(Name{n});
               app.PatientDropDown.Items = my_items;
            end

            app.time1 = 0;
            app.time2 = 20;
            xlim(app.UIAxes, [app.time1 app.time2])

        end

        % Value changed function: TimeLowerEditField
        function TimeLowerEditFieldValueChanged(app, event)
            value = app.TimeLowerEditField.Value;

            app.time1 = value;
            
        end

        % Value changed function: TimeHigherEditField
        function TimeHigherEditFieldValueChanged(app, event)
            value = app.TimeHigherEditField.Value;
            
            app.time2 = value;

        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged(app, event)
            value = app.PatientDropDown.Value;
            
            app.x2plot = app.data.(value).t;
            app.y2plot = app.data.(value).ecg;

        end

        % Value changed function: ApplyFilterCheckBox
        function ApplyFilterCheckBoxValueChanged(app, event)
            value = app.ApplyFilterCheckBox.Value;
            
            smoothECG = sgolayfilt(app.y2plot,7,201);
            plot(app.UIAxes, app.x2plot, smoothECG);
            xlim(app.UIAxes, [app.time1 app.time2])
            title(app.UIAxes, 'Filtered ECG');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            plot(app.UIAxes, app.x2plot, app.y2plot)
            xlim(app.UIAxes, [app.time1 app.time2])
            app.UIAxes.XGrid = "on";
            app.UIAxes.YGrid = "on";
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, app.PatientDropDown.Value);
            title(app.UIAxes, 'Patient ECG');
        end

        % Button pushed function: RwavePeaksButton
        function RwavePeaksButtonPushed(app, event)
            [~,locs_Rwave] = findpeaks(app.y2plot, 'MinPeakHeight', 0.5,...
                            'MinPeakDistance', 180);  

            plot(app.UIAxes, app.x2plot(locs_Rwave), app.y2plot(locs_Rwave),'rv','MarkerFaceColor','r');
            xlim(app.UIAxes, [app.time1 app.time2])
            title(app.UIAxes, 'R wave Peaks');
        end

        % Button pushed function: SwavePeaksButton
        function SwavePeaksButtonPushed(app, event)
            ECG_Inverted = -app.y2plot;
            [~,locs_Swave] = findpeaks(ECG_Inverted,'MinPeakHeight',-0.8, ...
                             'MinPeakDistance',180); 

            plot(app.UIAxes, app.x2plot(locs_Swave), app.y2plot(locs_Swave),'rs','MarkerFaceColor','b');
            xlim(app.UIAxes, [app.time1 app.time2])
            title(app.UIAxes, 'S wave Peaks');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ECGsinMatlabProjectLabel = uilabel(app.UIFigure);
            app.ECGsinMatlabProjectLabel.Text = 'ECGs in Matlab Project';
            app.PatientDropDownLabel = uilabel(app.UIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.UIFigure);
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ApplyFilterCheckBox = uicheckbox(app.UIFigure);
            app.ApplyFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterCheckBoxValueChanged, true);
            app.ApplyFilterCheckBox.Text = 'Apply Filter';
            app.TimeLowerEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLowerEditFieldLabel.Text = 'Time Lower';
            app.TimeLowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeLowerEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLowerEditFieldValueChanged, true);
            app.TimeHigherEditFieldLabel = uilabel(app.UIFigure);
            app.TimeHigherEditFieldLabel.Text = 'Time Higher';
            app.TimeHigherEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeHigherEditField.ValueChangedFcn = createCallbackFcn(app, @TimeHigherEditFieldValueChanged, true);
            app.TimeHigherEditField.Value = 20;
            app.RwavePeaksButton = uibutton(app.UIFigure, 'push');
            app.RwavePeaksButton.ButtonPushedFcn = createCallbackFcn(app, @RwavePeaksButtonPushed, true);
            app.RwavePeaksButton.Text = 'R wave Peaks';
            app.SwavePeaksButton = uibutton(app.UIFigure, 'push');
            app.SwavePeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SwavePeaksButtonPushed, true);
            app.SwavePeaksButton.Text = 'S wave Peaks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end